classdef mgloader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatlabGITloaderUIFigure  matlab.ui.Figure
        LocateGIT                matlab.ui.container.Menu
        CLOSEMenu                matlab.ui.container.Menu
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        SleepScore               matlab.ui.control.Button
        NLXload                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        GITLOC
        searchLOC % Description
    end
    
    methods (Access = private)
        
        function foldLIST = getFOLDlist(app)
            
            cd(app.searchLOC)
            
            tmp1 = dir;
            tmp2 = {tmp1.name};
            tmp3 = tmp2(~ismember(tmp2,{'.','..'}));
            
            foldLIST = tmp3;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NLXload
        function NLXloadButtonPushed(app, event)
            
            cd(app.GITLOC)
            
            nlxPATH = [app.GITLOC , filesep , 'NLX-Event-Viewer'];
            
            addpath(genpath(nlxPATH));
            
            % Add Query about Sleep score or Sleep process
            answer = uiconfirm(app.MatlabGITloaderUIFigure, 'Choose Sub Program',...
                'Which NLX', 'Options', {'NLX_View','NLX2Session','Cancel'},...
                'DefaultOption',1,'CancelOption',3);
            
            switch answer
                case 'Cancel'
                    return
                case 'NLX_View'
                    appdesigner('NLX_Event_Finder_v4.mlapp')
                case 'NLX2Session'
                    appdesigner('NLX_Session2Neuro_v6.mlapp')
            end
            MatlabGITloaderUIFigureCloseRequest(app)


        end

        % Menu selected function: LocateGIT
        function LocateGITMenuSelected(app, event)
            compNAME = getenv('COMPUTERNAME');
            
            if strcmp(compNAME, 'DESKTOP-95LU6PO')
                app.GITLOC = 'C:\Users\John\Documents\GitHub';
            end
            
            app.NLXload.Enable = 'on';
            app.SleepScore.Enable = 'on';
            app.Button_3.Enable = 'on';
            app.Button_4.Enable = 'on';
            
        end

        % Menu selected function: CLOSEMenu
        function CLOSEMenuSelected(app, event)
            delete(app)
        end

        % Button pushed function: SleepScore
        function SleepScoreButtonPushed(app, event)
            cd(app.GITLOC)
            
            nlxPATH = [app.GITLOC , filesep , 'SleepScoreGUI_jat'];
            
            addpath(nlxPATH);
            
            % Add Query about Sleep score or Sleep process
            
            % Load with Appdesigner
            
            % CD to folder
            
        end

        % Close request function: MatlabGITloaderUIFigure
        function MatlabGITloaderUIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatlabGITloaderUIFigure = uifigure('Visible', 'off');
            app.MatlabGITloaderUIFigure.Name = 'Matlab GIT loader';
            app.MatlabGITloaderUIFigure.CloseRequestFcn = createCallbackFcn(app, @MatlabGITloaderUIFigureCloseRequest, true);
            app.LocateGIT = uimenu(app.MatlabGITloaderUIFigure);
            app.LocateGIT.MenuSelectedFcn = createCallbackFcn(app, @LocateGITMenuSelected, true);
            app.LocateGIT.Text = 'Locate GIT';
            app.CLOSEMenu = uimenu(app.MatlabGITloaderUIFigure);
            app.CLOSEMenu.MenuSelectedFcn = createCallbackFcn(app, @CLOSEMenuSelected, true);
            app.CLOSEMenu.Text = 'CLOSE';
            app.NLXload = uibutton(app.MatlabGITloaderUIFigure, 'push');
            app.NLXload.ButtonPushedFcn = createCallbackFcn(app, @NLXloadButtonPushed, true);
            app.NLXload.Icon = 'NLXicon.png';
            app.NLXload.Enable = 'off';
            app.NLXload.Text = '';
            app.SleepScore = uibutton(app.MatlabGITloaderUIFigure, 'push');
            app.SleepScore.ButtonPushedFcn = createCallbackFcn(app, @SleepScoreButtonPushed, true);
            app.SleepScore.Icon = 'sleepscoreicon.png';
            app.SleepScore.Enable = 'off';
            app.SleepScore.Text = '';
            app.Button_3 = uibutton(app.MatlabGITloaderUIFigure, 'push');
            app.Button_3.Enable = 'off';
            app.Button_4 = uibutton(app.MatlabGITloaderUIFigure, 'push');
            app.Button_4.Enable = 'off';
            % Show the figure after all components are created
            app.MatlabGITloaderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mgloader

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatlabGITloaderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatlabGITloaderUIFigure)
        end
    end
end